classdef PDProfiler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        ApplicationLogLabel             matlab.ui.control.Label
        VersionLabel                    matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        SaveCalibrationButton           matlab.ui.control.Button
        Step4Instructions               matlab.ui.control.Label
        Step4Label                      matlab.ui.control.Label
        BrowseMeasuredButton            matlab.ui.control.Button
        MeasuredImageEditField          matlab.ui.control.EditField
        MeasuredImageEditFieldLabel     matlab.ui.control.Label
        BrowsePlannedButton             matlab.ui.control.Button
        PlannedDoseImageEditField       matlab.ui.control.EditField
        PlannedDoseImageEditFieldLabel  matlab.ui.control.Label
        BrowseCalibrationButton         matlab.ui.control.Button
        CalibrationProfileEditField     matlab.ui.control.EditField
        PVACalibrationProfileLabel      matlab.ui.control.Label
        Step3Label                      matlab.ui.control.Label
        Step2Instructions               matlab.ui.control.Label
        DownloadDICOMButton             matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Step2Label                      matlab.ui.control.Label
        Step1Label                      matlab.ui.control.Label
        Copyright                       matlab.ui.control.Label
        TitleLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        path % Description
        info % Description
        currentCalibration % Description
        currentFilename % Description
        targetProfile % Description
        measuredCenter % Description
        measuredImage % Description
        measuredImageRes % Description
        measuredProfile % Description
        newProfile % Description
        plotLegend % Description
    end
    
    methods (Access = private)
        
        % This function calculates the new, corrected file if all inputs
        % have been defined
        function CalculateCorrectedProfile(app)
            
            % If all three inputs are loaded
            if ~isempty(app.currentCalibration) && ~isempty(app.targetProfile) ...
                    && ~isempty(app.measuredProfile)
                Event('All input profiles loaded, computing optimized correction profile');

                % Run correction optimization, clipping edges (so that they
                % dont affect the center alignment)
                clip = 20;
                [app.measuredCenter, c] = OptimizeCorrection(app.targetProfile, ...
                    app.measuredImage(clip:end-clip,clip:end-clip), app.measuredImageRes);
                app.measuredCenter = app.measuredCenter + [clip clip];

                % Smooth coefficients
                c(2:end,2) = movmean(c(2:end,2), 3, 'Endpoints', 1);

                % Apply correction to new profile
                app.newProfile = app.currentCalibration;
                app.newProfile(:,end) = app.newProfile(:,end) ./ interp1(c(:,1), ...
                    c(:,2), app.newProfile(:,1), 'linear', 1);

                % Renormalize to 100
                app.newProfile(:,end) = app.newProfile(:,end) / app.newProfile(1,end) * 100;
            
            % Otherwise, inform user to continue loading files
            else
                Event('Corrected profile will be computed once all three input profiles are loaded');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Define version number
            version = '1.1.0';
            set(app.VersionLabel, 'Text', ['Version ', version]);

            % Determine path of current application
            [path, ~, ~] = fileparts(mfilename('fullpath')); %#ok<*ADPROP> 

            % Set current directory to location of this application
            cd(path);
            app.path = path;

            % Get MATLAB/OS versions, see LoadVersionInfo for more details
            app.info = LoadVersionInfo;

            % Store program and MATLAB/etc version information as a string cell array
            string = {'PDProfiler Panel Normalization Correction Tool'
                sprintf('Version: %s (%s)', version, app.info{6});
                sprintf('Author: Mark Geurts <mark.w.geurts@gmail.com>');
                sprintf('MATLAB Version: %s', app.info{2});
                sprintf('MATLAB License Number: %s', app.info{3});
                sprintf('Operating System: %s', app.info{1});
                sprintf('CUDA: %s', app.info{4});
                sprintf('Java Version: %s', app.info{5})
            };

            % Add dashed line separators
            separator = repmat('-', 1,  size(char(string), 2));
            string = sprintf('%s\n', separator, string{:}, separator);
    
            % Initialize data structures
            app.currentCalibration = [];
            app.targetProfile = [];
            app.measuredImage = [];
            app.measuredProfile = [];
            app.newProfile = [];

            % Initialize plots
            set(app.UIAxes, 'Visible', false);
            app.plotLegend = {'Current Normalization', ...
                    'Planned Open Field Profile', ...
                    'Measured Open Field Profile', ...
                    'Corrected Normalization'};
            set(app.UIAxes_2, 'Visible', false);

            % Log start
            Event(string, 'INIT', app.UITable);
            Event('PDProfiler GUI initialization completed successfully');

            % Clean up
            clear path separator string;
        end

        % Button pushed function: DownloadDICOMButton
        function DownloadDICOMButtonPushed(app, event)
            
            % Request the user to select the image file
            Event('UI window opened to select save file');
            [name, path] = uiputfile('RTPLAN_Open_40x40.dcm', ...
                'Save DICOM RT Plan');

            % If the user selected a file, copy DICOM Plan to that folder
            if ~isequal(name, 0)
                copyfile('RTPLAN_Open_40x40.dcm', fullfile(path, name), 'f');
                Event(['RTPLAN_Open_40x40.dcm saved to ', fullfile(path, name)]);

             % Otherwise the user did not select a file
            else
                Event('User cancelled file selection');
            end
        end

        % Button pushed function: BrowseCalibrationButton
        function BrowseCalibrationButtonPushed(app, event)
            
            % Request the user to select the normalization file
            Event('UI window opened to select PVA Calibration profile');
            [name, path] = uigetfile({'*.cdp', 'W2CAD File (*.cdp)'}, ...
                'Select Calibration Profile', app.path); %#ok<*ADPROPLC> 

            % If the user selected a file
            if ~isequal(name, 0)

                % Log event
                Event(['User selected ', fullfile(path, name)]);

                % Update default path
                Event(['Default file path updated to ', path]);
                app.path = path;

                % Set UI field with filename
                set(app.CalibrationProfileEditField, 'Value', name);
                app.currentFilename = fullfile(path, name);

                % Load file
                Event(['Loading calibration CDP file ', name]);
                app.currentCalibration = ...
                    sortrows(LoadCDP(fullfile(path, name)));
                
                % Save existing filename, if all code executed
                app.currentFilename = fullfile(path, name);
                Event(sprintf('Calibration CDP file successfully loaded with %i elements', ...
                    size(app.currentCalibration, 1)));

                % Calculate corrected profile, if able
                CalculateCorrectedProfile(app);

                % Update profile plot
                Event('Plotting calibration profile');
                PlotNormalizedProfiles({app.currentCalibration, ...
                    app.targetProfile, ...
                    app.measuredProfile, ...
                    app.newProfile}, ...
                    app.UIAxes_2, 'first');
                set(app.UIAxes_2, 'Visible', true);
                legend(app.UIAxes_2, app.plotLegend, 'Location', 'southwest');

            % Otherwise the user did not select a file
            else
                Event('User cancelled file selection');
            end

            % Clear temporary variables
            clear name path;
        end

        % Button pushed function: BrowsePlannedButton
        function BrowsePlannedButtonPushed(app, event)
            
            % Request the user to select the planned dose image
             Event('UI window opened to select planned image file');
            [name, path] = uigetfile({'*.dxf', 'DXF File (*.dxf)'}, ...
                'Select Planned Open Field Image', app.path); %#ok<*ADPROPLC> 

            % If the user selected a file
            if ~isequal(name, 0)

                % Log event
                Event(['User selected ', fullfile(path, name)]);

                % Update default path
                Event(['Default file path updated to ', path]);
                app.path = path;

                % Load file
                Event(['Loading planned DXF file ', name]);
                [~, targetResolution, targetImage, ~] = ...
                    LoadDXF(fullfile(path, name), true);

                % Extract radial profile
                Event(sprintf('DXF loaded with dimensions %i x %i, extracting radial profile', ...
                    size(targetImage)));
                [x, y] = pol2cart(repmat(atan(size(targetImage,1) / ...
                    size(targetImage,2)), [1 size(targetImage,1)]), ...
                    0:size(targetImage,1)-1);
                x = x + size(targetImage,2)/2 + 0.5;
                y = y + size(targetImage,1)/2 + 0.5;
                app.targetProfile = zeros(size(targetImage,1), 2);
                app.targetProfile(:,2) = interp2(targetImage, x, y, 'linear', NaN);
                app.targetProfile(:,1) = (0:size(app.targetProfile,1)-1) * ...
                    targetResolution(1);
                app.targetProfile = app.targetProfile(~isnan(app.targetProfile(:,2)), :);
                clear x y;

                % Set UI field with filename
                set(app.PlannedDoseImageEditField, 'Value', name);
                Event('Planned DXF successfully parsed');

                % Calculate corrected profile, if able
                CalculateCorrectedProfile(app);

                % Update profile plot
                PlotNormalizedProfiles({app.currentCalibration, ...
                    app.targetProfile, ...
                    app.measuredProfile, ...
                    app.newProfile}, ...
                    app.UIAxes_2, 'first');
                set(app.UIAxes_2, 'Visible', true);
                legend(app.UIAxes_2, app.plotLegend, 'Location', 'southwest');

            % Otherwise the user did not select a file
            else
                Event('User cancelled file selection');
            end

            % Clear temporary variables
            clear name path targetImage targetResolution;
        end

        % Button pushed function: BrowseMeasuredButton
        function BrowseMeasuredButtonPushed(app, event)
            
            % Request the user to select the image file
            Event('UI window opened to select measured image file');
            [name, path] = uigetfile({'*.dxf', 'DXF File (*.dxf)'}, ...
                'Select Measured Open Field Image', app.path); %#ok<*ADPROPLC> 

            % If the user selected a file
            if ~isequal(name, 0)

                % Log event
                Event(['User selected ', fullfile(path, name)]);

                % Update default path
                Event(['Default file path updated to ', path]);
                app.path = path;

                % Load image file
                Event(['Loading measured DXF file ', name]);
                [~, app.measuredImageRes, app.measuredImage, ~] = ...
                    LoadDXF(fullfile(path, name), true);

                % Set UI field with filename
                set(app.MeasuredImageEditField, 'Value', name);
                Event('Measured DXF successfully loaded');

                % Find symmetric center profile of measured image
                [x, y] = pol2cart(repmat(atan(size(app.measuredImage,1) / ...
                    size(app.measuredImage,2)), [1 size(app.measuredImage,1)]), ...
                    0:size(app.measuredImage,1)-1);
                x = x + size(app.measuredImage,2)/2 + 0.5;
                y = y + size(app.measuredImage,1)/2 + 0.5;
                app.measuredProfile = zeros(size(app.measuredImage,1), 2);
                app.measuredProfile(:,2) = interp2(app.measuredImage, x, y, 'linear', NaN);
                app.measuredProfile(:,1) = (0:size(app.measuredProfile,1)-1) * app.measuredImageRes(1);
                app.measuredProfile = app.measuredProfile(~isnan(app.measuredProfile(:,2)), :);
                clear x y;

                % Calculate corrected profile, if able
                CalculateCorrectedProfile(app);

                % Update profile plot
                PlotNormalizedProfiles({app.currentCalibration, ...
                    app.targetProfile, ...
                    app.measuredProfile, ...
                    app.newProfile}, ...
                    app.UIAxes_2, 'first');
                set(app.UIAxes_2, 'Visible', true);
                legend(app.UIAxes_2, app.plotLegend, 'Location', 'southwest');

                % Plot measured image and center
                imagesc(app.UIAxes, app.measuredImage');
                xlim(app.UIAxes, [0 size(app.measuredImage, 1)-1]);
                ylim(app.UIAxes, [0 size(app.measuredImage, 2)-1]);
                set(app.UIAxes, 'yTickLabel', []);
                set(app.UIAxes, 'xTickLabel', []);
                set(app.UIAxes, 'Ticklength', [0 0]);
                colorbar(app.UIAxes, 'eastoutside');
                if ~isempty(app.measuredCenter)
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, app.measuredCenter(1), app.measuredCenter(2), 'b+');
                    hold(app.UIAxes, 'off');
                end
                set(app.UIAxes, 'Visible', true);

            % Otherwise the user did not select a file
            else
                Event('User cancelled file selection');
            end

            % Clear temporary variables
            clear name path res image center profile;
        end

        % Button pushed function: SaveCalibrationButton
        function SaveCalibrationButtonPushed(app, event)
            
            % Request the user to select the image file
            Event('UI window opened to select save file');
            [name, path] = uiputfile({'*.cdp', 'W2CAD File (*.cdp)'}, ...
                'Save Corrected File'); %#ok<*ADPROPLC> 

            % If the user selected a file, run SaveCDP
            if ~isequal(name, 0)
                Event(['Saving corrected profile to ', fullfile(path, name)]);
                SaveCDP(app.newProfile, fullfile(path, name), ...
                    app.currentFilename);
                Event('Corrected profile successfully saved in CDP format');

             % Otherwise the user did not select a file
            else
                Event('User cancelled file selection');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Portal Dosimetry Profiler';
            app.Copyright = uilabel(app.UIFigure);
            app.Copyright.Text = 'Copyright (c) 2022 Aspirus Health. Authored by Mark Geurts';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Step1Label = uilabel(app.Panel_3);
            app.Step1Label.Text = 'Step 1: Measure open field';
            app.Step2Label = uilabel(app.Panel_3);
            app.Step2Label.Text = 'Step 2: Retrieve input files from system';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = {'Download the provided DICOM file and create a Portal '; 'Dosimetry verification plan. Measure it on your panel.'};
            app.DownloadDICOMButton = uibutton(app.Panel_3, 'push');
            app.DownloadDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadDICOMButtonPushed, true);
            app.DownloadDICOMButton.Text = 'Download DICOM';
            app.Step2Instructions = uilabel(app.Panel_3);
            app.Step2Instructions.Text = {'Export the planned portal image dose and measured MV image from portal dosimetry '; 'as text (.dxf) files. Also retrieve the existing normalization profile used in PVA '; 'Calibration, in W2CAD (.cdp) format.'};
            app.Step3Label = uilabel(app.Panel_3);
            app.Step3Label.Text = 'Step 3: Select the input files';
            app.PVACalibrationProfileLabel = uilabel(app.Panel_3);
            app.PVACalibrationProfileLabel.Text = 'PVA Calibration Profile';
            app.CalibrationProfileEditField = uieditfield(app.Panel_3, 'text');
            app.CalibrationProfileEditField.Value = 'Choose a .cdp file';
            app.BrowseCalibrationButton = uibutton(app.Panel_3, 'push');
            app.BrowseCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCalibrationButtonPushed, true);
            app.BrowseCalibrationButton.Text = 'Browse';
            app.PlannedDoseImageEditFieldLabel = uilabel(app.Panel_3);
            app.PlannedDoseImageEditFieldLabel.Text = 'Planned Dose Image';
            app.PlannedDoseImageEditField = uieditfield(app.Panel_3, 'text');
            app.PlannedDoseImageEditField.Value = 'Choose a .dxf file';
            app.BrowsePlannedButton = uibutton(app.Panel_3, 'push');
            app.BrowsePlannedButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsePlannedButtonPushed, true);
            app.BrowsePlannedButton.Text = 'Browse';
            app.MeasuredImageEditFieldLabel = uilabel(app.Panel_3);
            app.MeasuredImageEditFieldLabel.Text = 'Measured Image';
            app.MeasuredImageEditField = uieditfield(app.Panel_3, 'text');
            app.MeasuredImageEditField.Value = 'Choose a .dxf file';
            app.BrowseMeasuredButton = uibutton(app.Panel_3, 'push');
            app.BrowseMeasuredButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseMeasuredButtonPushed, true);
            app.BrowseMeasuredButton.Text = 'Browse';
            app.Step4Label = uilabel(app.Panel_3);
            app.Step4Label.Text = 'Step 4: Save Corrected Calibration Profile';
            app.Step4Instructions = uilabel(app.Panel_3);
            app.Step4Instructions.Text = {'Save the new corrected calibration profile and re-calibrate your'; 'MV panel in PVA calibration with it.'};
            app.SaveCalibrationButton = uibutton(app.Panel_3, 'push');
            app.SaveCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCalibrationButtonPushed, true);
            app.SaveCalibrationButton.Text = 'Save Calibration';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Panel_2);
            title(app.UIAxes_2, 'Profiles')
            xlabel(app.UIAxes_2, 'Position (mm)')
            ylabel(app.UIAxes_2, 'Normalized Value')
            zlabel(app.UIAxes_2, 'Z')
            app.VersionLabel = uilabel(app.UIFigure);
            app.VersionLabel.Text = 'Version 1.1.0';
            app.Panel = uipanel(app.UIFigure);
            app.ApplicationLogLabel = uilabel(app.Panel);
            app.ApplicationLogLabel.Text = 'Application Log';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PDProfiler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end